classdef Slidip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlidipUIFigure               matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        NextButton                   matlab.ui.control.Button
        BackButton                   matlab.ui.control.Button
        SobelthresholdSpinnerLabel   matlab.ui.control.Label
        SobelthresholdSpinner        matlab.ui.control.Spinner
        AngularStepRadSpinnerLabel   matlab.ui.control.Label
        AngularStepRadSpinner        matlab.ui.control.Spinner
        PixelStepSpinnerLabel        matlab.ui.control.Label
        PixelStepSpinner             matlab.ui.control.Spinner
        LoadImageButton              matlab.ui.control.Button
        HoughThresholdSpinnerLabel   matlab.ui.control.Label
        HoughThresholdSpinner        matlab.ui.control.Spinner
        MaxLineDistanceSpinnerLabel  matlab.ui.control.Label
        MaxLineDistanceSpinner       matlab.ui.control.Spinner
        WeightSpinnerLabel           matlab.ui.control.Label
        WeightSpinner                matlab.ui.control.Spinner
        CleanupSwitchLabel           matlab.ui.control.Label
        CleanupSwitch                matlab.ui.control.Switch
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        State = 0; % This variable holds the current state
        MaxState = 5; % This variable represent the highest possible state
        RawImage = 0; % This variable stores the unprocessed image
        SobelImage = 0;
        HoughImage = 0;
        HoughThreshold = 0;
        FinishedImage = 0;
        Alpha % Description
        Distance % Description
        HoughParasChanged = true; % Description
    end
    
    methods (Access = private)
        % sloppy pseudo state machine
        function results = executeStateUpdate(app)
            results = 0;
            switch app.State
                case 0 % no image available
                    app.UIAxes.Title.String = 'No Image available';
                    app.NextButton.Enable = false;
                    app.BackButton.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularStepRadSpinner.Enable = false;
                    app.HoughThresholdSpinner.Enable = false;
                    app.PixelStepSpinner.Enable = false;   
                    app.MaxLineDistanceSpinner.Enable = false;
                    app.WeightSpinner.Enable = false;
                    app.CleanupSwitch.Enable = false;
                    disp(app.State)
                    
                case 1 % image loaded
                    app.UIAxes.Title.String = 'Image Preview';
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularStepRadSpinner.Enable = false;
                    app.HoughThresholdSpinner.Enable = false;
                    app.PixelStepSpinner.Enable = false;
                    app.MaxLineDistanceSpinner.Enable = false;
                    app.WeightSpinner.Enable = false;
                    app.CleanupSwitch.Enable = false;
                    imagesc(app.UIAxes, app.RawImage);
                    
                case 2 % sobel
                    app.UIAxes.Title.String = 'Sobel filter';
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.SobelthresholdSpinner.Enable = true;
                    app.AngularStepRadSpinner.Enable = false;
                    app.PixelStepSpinner.Enable = false;
                    app.HoughThresholdSpinner.Enable = false;
                    app.MaxLineDistanceSpinner.Enable = false;
                    app.WeightSpinner.Enable = false;
                    app.CleanupSwitch.Enable = false;
                    app.ApplySobelOperator()
                    imagesc(app.UIAxes, app.SobelImage);
                    
                case 3 % hough trafo               
                    disp("hough trafo")
                    app.UIAxes.Title.String = 'Hough transformation... Processing';
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularStepRadSpinner.Enable = true;
                    app.PixelStepSpinner.Enable = true;
                    app.MaxLineDistanceSpinner.Enable = false;
                    app.WeightSpinner.Enable = false;
                    app.HoughThresholdSpinner.Enable = false;
                    app.CleanupSwitch.Enable = false;
                    drawnow;
                    app.ApplyHoughTransformation();
                    imagesc(app.UIAxes, app.HoughImage);
                    app.UIAxes.Title.String = 'Hough transformation';                    
                
                case 4 % threshold
                    app.UIAxes.Title.String = 'Hough Treshold';
                    app.NextButton.Enable = true;
                    app.BackButton.Enable = true;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularStepRadSpinner.Enable = false;
                    app.PixelStepSpinner.Enable = false;
                    app.MaxLineDistanceSpinner.Enable = false;
                    app.WeightSpinner.Enable = false;
                    app.HoughThresholdSpinner.Enable = true;
                    app.CleanupSwitch.Enable = false;
                    app.ApplyThreshold()
                    imagesc(app.UIAxes, app.HoughThreshold);
                    
                case 5 % reverse hogh trafo                    
                    app.UIAxes.Title.String = 'Reconstructing image... Processing';
                    app.NextButton.Enable = false;
                    app.BackButton.Enable = true;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularStepRadSpinner.Enable = false;
                    app.HoughThresholdSpinner.Enable = false;
                    app.PixelStepSpinner.Enable = false;
                    app.MaxLineDistanceSpinner.Enable = true;
                    app.WeightSpinner.Enable = true;
                    app.CleanupSwitch.Enable = true;
                    drawnow;
                    app.FinishedImage = hough2lines( ...
                        app.HoughThreshold, ...                      
                        size(app.RawImage), ...
                        app.MaxLineDistanceSpinner.Value, ...
                        app.WeightSpinner.Value, ...
                        app.AngularStepRadSpinner.Value, ...
                        app.Alpha, ...
                        app.Distance);
                    if app.CleanupSwitch.Value == "On"
                        app.FinishedImage = app.FinishedImage.*app.SobelImage;
                        disp("sobelsobel")
                    end
                    imagesc(app.UIAxes, app.FinishedImage);
                    app.UIAxes.Title.String = 'Reconstructed image';
                    
                otherwise
                    results = -1;
                    disp("unknown state")
            end
        end
        
        function results = LoadImage(app)
            results = 0;
            [file, path, ~] = uigetfile({'*.jpg', 'JPEG'; '*.png', 'PNG'}, "please choose a picture");
            filepath = strcat(path, file);
            if filepath ~= ""
                results = imread(filepath);
            end
        end
        
        function results = ApplySobelOperator(app)
            value = app.SobelthresholdSpinner.Value;
            app.SobelImage = threshold(sobeloperator(rgb2bw(app.RawImage)), value);
        end
        
        function results = ApplyHoughTransformation(app)
            pistep = app.AngularStepRadSpinner.Value;
            dstep = app.PixelStepSpinner.Value;
            [app.HoughImage, app.Distance, app.Alpha] = houghtransformation(app.SobelImage, pistep, dstep);
        end
        
        function results = ApplyThreshold(app)
            value = app.HoughThresholdSpinner.Value;
            app.HoughThreshold = threshold(app.HoughImage, value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppStatup(app)
            app.executeStateUpdate()
        end

        % Button pushed function: NextButton
        function BNextPushed(app, event)
            if app.State < app.MaxState
                app.State = app.State + 1;
            end
            app.executeStateUpdate();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SlidipUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {580, 580};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: BackButton
        function BBackPushed(app, event)
            if app.State > 0
                app.State = app.State - 1;
            end
            app.executeStateUpdate();
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
           app.RawImage = app.LoadImage();
           app.State = 1;
           if app.RawImage == 0
               app.State = 0;
           end
           app.executeStateUpdate();
        end

        % Value changed function: SobelthresholdSpinner
        function SobelThresholdChanged(app, event)
            app.executeStateUpdate()
        end

        % Callback function
        function SobelThresholdChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: AngularStepRadSpinner
        function AngularStepRadChanging(app, event)
            app.executeStateUpdate();
            
        end

        % Value changed function: PixelStepSpinner
        function PixelStep(app, event)
            app.executeStateUpdate();
            
        end

        % Value changed function: HoughThresholdSpinner
        function HoughThresholdChanging(app, event)
            app.executeStateUpdate()
            
        end

        % Value changed function: MaxLineDistanceSpinner
        function MaxLineDistanceChanged(app, event)
            app.executeStateUpdate();
            
        end

        % Value changed function: WeightSpinner
        function WeightChanged(app, event)
            app.executeStateUpdate();
            
        end

        % Value changed function: CleanupSwitch
        function CleanupSwitchChanged(app, event)
            app.executeStateUpdate();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlidipUIFigure = uifigure('Visible', 'off');
            app.SlidipUIFigure.Name = 'Slidip';
            app.SlidipUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SlidipUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @BNextPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.GridLayout2, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BBackPushed, true);
            app.BackButton.Text = 'Back';
            app.SobelthresholdSpinnerLabel = uilabel(app.GridLayout2);
            app.SobelthresholdSpinnerLabel.Text = 'Sobel threshold';
            app.SobelthresholdSpinner = uispinner(app.GridLayout2);
            app.SobelthresholdSpinner.Step = 0.01;
            app.SobelthresholdSpinner.Limits = [0 1];
            app.SobelthresholdSpinner.ValueDisplayFormat = '%.2f';
            app.SobelthresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @SobelThresholdChanged, true);
            app.AngularStepRadSpinnerLabel = uilabel(app.GridLayout2);
            app.AngularStepRadSpinnerLabel.Text = 'Angular Step [Rad]';
            app.AngularStepRadSpinner = uispinner(app.GridLayout2);
            app.AngularStepRadSpinner.Step = 0.01;
            app.AngularStepRadSpinner.Limits = [0.01 1];
            app.AngularStepRadSpinner.ValueDisplayFormat = '%.2f';
            app.AngularStepRadSpinner.ValueChangedFcn = createCallbackFcn(app, @AngularStepRadChanging, true);
            app.AngularStepRadSpinner.Value = 0.01;
            app.PixelStepSpinnerLabel = uilabel(app.GridLayout2);
            app.PixelStepSpinnerLabel.Text = 'Pixel Step';
            app.PixelStepSpinner = uispinner(app.GridLayout2);
            app.PixelStepSpinner.Limits = [1 100];
            app.PixelStepSpinner.ValueChangedFcn = createCallbackFcn(app, @PixelStep, true);
            app.PixelStepSpinner.Value = 10;
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.HoughThresholdSpinnerLabel = uilabel(app.GridLayout2);
            app.HoughThresholdSpinnerLabel.Text = 'Hough Threshold';
            app.HoughThresholdSpinner = uispinner(app.GridLayout2);
            app.HoughThresholdSpinner.Step = 0.01;
            app.HoughThresholdSpinner.Limits = [0 1];
            app.HoughThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @HoughThresholdChanging, true);
            app.MaxLineDistanceSpinnerLabel = uilabel(app.GridLayout2);
            app.MaxLineDistanceSpinnerLabel.Text = 'Max Line Distance';
            app.MaxLineDistanceSpinner = uispinner(app.GridLayout2);
            app.MaxLineDistanceSpinner.Limits = [0 Inf];
            app.MaxLineDistanceSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxLineDistanceChanged, true);
            app.WeightSpinnerLabel = uilabel(app.GridLayout2);
            app.WeightSpinnerLabel.Text = 'Weight';
            app.WeightSpinner = uispinner(app.GridLayout2);
            app.WeightSpinner.Limits = [1 100];
            app.WeightSpinner.ValueChangedFcn = createCallbackFcn(app, @WeightChanged, true);
            app.WeightSpinner.Value = 1;
            app.CleanupSwitchLabel = uilabel(app.GridLayout2);
            app.CleanupSwitchLabel.Text = {'Cleanup'; ''};
            app.CleanupSwitch = uiswitch(app.GridLayout2, 'slider');
            app.CleanupSwitch.ValueChangedFcn = createCallbackFcn(app, @CleanupSwitchChanged, true);
            app.CleanupSwitch.Value = 'On';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.SlidipUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Slidip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlidipUIFigure)

            % Execute the startup function
            runStartupFcn(app, @AppStatup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlidipUIFigure)
        end
    end
end